classdef digi2excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CMDropDown            matlab.ui.control.DropDown
        CMDropDownLabel       matlab.ui.control.Label
        CButton               matlab.ui.control.Button
        TestLogButton         matlab.ui.control.Button
        LogAreaTextArea       matlab.ui.control.TextArea
        LogAreaTextAreaLabel  matlab.ui.control.Label
        BeginButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        StrArray % for logging
    end
    
    methods (Access = private)
        
        function log_func(app, logStr)
            app.StrArray = [app.StrArray, {sprintf(logStr)}]; % sprintf为了转义
            app.StrArray = [app.StrArray, {sprintf('----------+++----------')}];
            app.LogAreaTextArea.Value = app.StrArray;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            className = class(app);  % 获取当前app的类名
            classPath = which(className);
            log_func(app, ['APPPath:  ', classPath])

            a = uigetdir();
            log_func(app, ['NewPath:  ', a])
            cd(a);
            
            log_func(app, ['CurrentPath:  ', pwd])
            
            logging = digitalMicDataTool(app.CMDropDown.Value);
            log_func(app, logging)
        end

        % Callback function
        function TestLogButtonPushed(app, event)

        end

        % Button pushed function: TestLogButton
        function TestLogButtonPushed2(app, event)
            for i = 1:10
                pause(0.1);
                log_func(app, char(string(i)));
            end            
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.LogAreaTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.LogAreaTextAreaLabel = uilabel(app.UIFigure);
            app.LogAreaTextAreaLabel.Text = 'Log Area';
            app.LogAreaTextArea = uitextarea(app.UIFigure);
            app.TestLogButton = uibutton(app.UIFigure, 'push');
            app.TestLogButton.ButtonPushedFcn = createCallbackFcn(app, @TestLogButtonPushed2, true);
            app.TestLogButton.Text = 'TestLog';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.CMDropDownLabel = uilabel(app.UIFigure);
            app.CMDropDownLabel.Text = 'CM';
            app.CMDropDown = uidropdown(app.UIFigure);
            app.CMDropDown.Items = {'Mflex', 'Flexium', 'QSMC', 'WTKM'};
            app.CMDropDown.Value = 'Mflex';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = digi2excel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end